classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        XbeeModuleReadyLamp            matlab.ui.control.Lamp
        XbeeModuleReadyLampLabel       matlab.ui.control.Label
        XbeeConnectionReadyLamp        matlab.ui.control.Lamp
        XbeeConnectionReadyLampLabel   matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        XbeeSerialPortTextArea         matlab.ui.control.TextArea
        XbeeSerialPortTextAreaLabel    matlab.ui.control.Label
        GaugeSteering                  matlab.ui.control.LinearGauge
        ModeSwitchButton               matlab.ui.control.StateButton
        XbeeConnectButton              matlab.ui.control.Button
        ControllerConnectButton        matlab.ui.control.Button
        ControllerSerialPortDropDown   matlab.ui.control.DropDown
        ControllerSerialPortDropDownLabel  matlab.ui.control.Label
        STOPButton                     matlab.ui.control.StateButton
        CameraReadyLamp                matlab.ui.control.Lamp
        CameraReadyLampLabel           matlab.ui.control.Label
        SteeringWheelReadyLampLabel_3  matlab.ui.control.Label
        SteeringWheelReadyLampLabel_2  matlab.ui.control.Label
        SystemlStopLamp                matlab.ui.control.Lamp
        SystemlStopLampLabel           matlab.ui.control.Label
        ObstacleAvoidingLamp           matlab.ui.control.Lamp
        ObstacleAvoidingLampLabel      matlab.ui.control.Label
        ManualControlLamp              matlab.ui.control.Lamp
        ManualControlLampLabel         matlab.ui.control.Label
        SystemReadyLamp                matlab.ui.control.Lamp
        SystemReadyLampLabel           matlab.ui.control.Label
        SystemCheckButton              matlab.ui.control.Button
        AutoControlLamp                matlab.ui.control.Lamp
        AutoControlLampLabel           matlab.ui.control.Label
        LAUNCHButton                   matlab.ui.control.Button
        ControllerConnectionReadyLamp  matlab.ui.control.Lamp
        ControllerConnectionReadyLampLabel  matlab.ui.control.Label
        GaugeTestButton                matlab.ui.control.Button
        Gauge                          matlab.ui.control.Gauge
        GaugeLabel                     matlab.ui.control.Label
        VideoURLEditField              matlab.ui.control.EditField
        VideoURLEditFieldLabel         matlab.ui.control.Label
        EndVideoTransmissionButton     matlab.ui.control.Button
        StartVideoTransmissionButton   matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        TimerHandle;    % Timer interrupt handle
        cam;    % Webcam Variable
        VideoURL;   % URL of video feed
        TimerPeriod;
        ControllerPort;  % Serial port of the input
        XbeePort; % Serial port of the output
        Mode; % operation mode, 0 for manual, 1 for auto
        
        % Serial Related parameters
        SerialXbee; % creating an serial object
        joy; % creating a joystick object
        
        % essential variables
        feedback; % system check feedback
        ModeSelect; % spesifies the mode of operation
        Counter; % quantifies the number of times 
        stop; % used for E stop handling
        
        % data type spesifictaion
        motor_data = uint8(0x0A); % manual mode data
        auto_mode = uint8(0x0B); % auto mode data 
        xbee_check = uint8(0x0C); % system check data
    end

    methods (Access = public)
        
        % Main function to excute when system is ready
        function RobotRun(app)
            
            % enable UI input
            app.STOPButton.Enable = 1;
            app.ModeSwitchButton.Enable = 1;
           
            % Infinite while loop to recive/transmit serial data
            while true
                %% Pause the program for one ms, and check if the emergency stop button is pressed or not.
                pause(0.001)
                
                % handles case when E stop button is pushed
                if app.STOPButton.Value == true
                    % indicate 'stop' state on the interface
                    app.Gauge.Value = 0;
                    app.SystemlStopLamp.Enable = 1;
                    app.STOPButton.Value = false;
                    
                    % instruct the robot to come to rest
                    transmit = compose_package([125,125],app.Stop,3,0);
                    transmit_message(app.SerialXbee,transmit);
                    
                    % monitor robot feedback
                    receive = readline(app.SerialXbee);
                    
                    % break through the loop, wait for user input
                    break;
                end
                
                % When button is pushed the state of system operation
                % swiches
                if app.ModeSwitchButton.Value == true
                    app.ModeSelect = 1; % auto
                else
                    app.ModeSelect = 0; % manual
                end
                
                %% Display the system current state
                if app.ModeSelect == 0 % manual mode
                    app.ManualControlLamp.Enable = 1;
                    app.ObstacleAvoidingLamp.Enable = 0;
                    app.AutoControlLamp.Enable = 0;
   
                elseif app.ModeSelect == 1 % auto mode
                    app.ManualControlLamp.Enable = 0;
                    app.ObstacleAvoidingLamp.Enable = 0;
                    app.AutoControlLamp.Enable = 1; 
                    
                    % if this is the first loop for auto, instruct PIC to
                    % enter auto operation state
                    if app.Counter == 0
                        % transmit the auto info to PIC
                        transmit = compose_package([0,0],app.auto_mode,3,0);
                        transmit_message(app.SerialXbee,transmit);
                        % incrementing the counter to avoid sending this
                        % signal again
                        app.Counter = 1;
                    end               
                end
                
               
                %% Read the input infomation from the steering wheel/controller
               if app.ModeSelect == 0 
                    app.Counter = 0; % allow the auto mode to be triggered again
                   
                    % extract user input data, x and y axis, scale to 0 to
                    % 250
                    x = uint8(interp1([-1,1], [0,250], -1*axis(app.joy,2)));
                    y = uint8(interp1([-1,1], [0,250], axis(app.joy,4)));
     
                    %processing the data to be sent through Xbee, decrease
                    %the precision to reduce data oscillation.
                    x_in = round(x/25)*25;
                    y_in = round(y/25)*25;
                    
                    % convert x_in/y_in into double to allow caluclation
                    % and display on the gauge
                    x_in_buffer = double(x_in);
                    y_in_buffer = double(y_in);
                    
                    % process the data to give percentage reading then
                    % diaplay them on the linear/circular gauge
                    app.Gauge.Value = abs(x_in_buffer-125)/1.25;
                    app.GaugeSteering.Value = (y_in_buffer-125)/1.25;
                    pause(0.001)
                    
                    % transmit the input to PIC
                    user_in = [x_in,y_in];
                    transmit = compose_package(user_in,app.motor_data,3,0);
                    transmit_message(app.SerialXbee,transmit); %Send package via Xbee

                    % poll for PIC feedback & determine further operations
                    receive_message(app.SerialXbee,app);
                    
               elseif app.ModeSelect == 1 % auto mode
                   
                   % When counter = 1, the UI has transmitted the auto
                   % instrutcion to PIC, thus PIC should provide a feedback
                   if app.Counter == 1
                       receive_message(app.SerialXbee,app);
                       app.Counter = 2;
                       
                   % after the initial feedback, the next feedback provided
                   % by PIC whould indicate the system has avoided an obstacle 
                   % and operation state is back to manual mode,thus need to be handled
                   % differently
                   else
                        buffer = 0; 
                        % when either case is true, return to manual mode
                        while (isnumeric(buffer) && (app.ModeSelect == 1))
                            % check user state transition input
                            if app.ModeSwitchButton.Value == false
                                app.ModeSelect = 0;
                            end
                            
                            % check for PIC response
                            app.SerialXbee.Timeout = 1;
                            buffer = readline(app.SerialXbee);
                        end
                        
                        % returning to manual mode
                        app.ModeSelect = 0;    
                        
                        % restore the serial time out period
                        app.SerialXbee.Timeout = 10;
                   end
                  
               elseif app.ModeSelect == 2 % avoidance state
                    
                    % illustrate current operation state on the interface
                    app.ManualControlLamp.Enable = 0;
                    app.AutoControlLamp.Enable = 0;
                    app.ObstacleAvoidingLamp.Enable = 1;
                    
                    buffer = 0; %initialisation
                    % Pause before polling in while loop
                    pause(0.001);
                    
                    % when the robot is completing obstacle avoidance routine 
                    while ((isnumeric(buffer)) && (app.STOPButton.Value ~= true))
                        % check whether the user wish to stop the robot
                        if app.STOPButton.Value == true
                            % when robot pauses, speed = 0 
                            app.stop = 1;
                            app.GaugeSteering.Value = 0;
                            app.Gauge.Value = 0;
                            
                            % instruct PIC to stop
                            transmit = compose_package([125,125],app.Stop,3,0);
                            transmit_message(app.SerialXbee,transmit);
                            
                            % wait for PIC response, handle exception
                            app.SerialXbee.Timeout = 10;
                            receive = readline(app.SerialXbee);
                            
                            % display current system state
                            app.STOPButton.Value = false;
                            break;% break inner while
                        end
                        
                        % check for avoidance completion, when buffer is
                        % not numeric, avoidance routine is finished
                        app.SerialXbee.Timeout = 1;
                        buffer = readline(app.SerialXbee);
                    end
                    
                    % break the larger while loop
                    if app.stop == 1
                         app.stop = 0;     
                         break;% break larger while
                    end
                    
                    % After each stop, current mode would automatically
                    % swich to manual mode
                    app.ModeSelect = 0;   
                    % reset serial timeout, previously adjusted for
                    % avoidance routine completion check
                    app.SerialXbee.Timeout = 10;
                       
               end
                 
            end
            % reset display status
            app.STOPButton.Enable = 0;
            app.ModeSwitchButton.Enable = 0;
            app.ManualControlLamp.Enable = 0;
            app.AutoControlLamp.Enable = 0;
            app.ObstacleAvoidingLamp.Enable = 0;
            app.SystemlStopLamp.Enable = 1;
            pasue(0.01)
        end
    end        
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Initialization
            
            % Axis Config. Set it to be invisiable at first
            app.UIAxes.Visible = 'off';
            % Period of timer interrupt;
            app.TimerPeriod = 1;
            % Disable stop button
            app.STOPButton.Enable = 0;
            % Enable mode switch button
            app.ModeSwitchButton.Enable = 0;
            % default set each lamp indictaors
            app.XbeeModuleReadyLamp.Enable = 0;
            app.XbeeConnectionReadyLamp.Enable = 0;
            % Default port is 'COM3'
            app.XbeeSerialPortTextArea.Value = "COM3";
            % initialise these variables with default values
            app.ModeSelect = 0;
            app.Counter = 0;
            app.stop = 0;
            
            
            % Interface screen resizing, talored to scrren size
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height]; 
         
           
        end

        % Button pushed function: StartVideoTransmissionButton
        function StartVideoTransmissionButtonPushed(app, event)
            error = 0;  
            % try to load the video 
            try
                app.cam = ipcam(app.VideoURL);
            catch 
                f = msgbox('Invalid URL Input', 'Error','error');
                error = 1;
            end
            
            % if connection succesfully established, display the video on
            % the interface and light up the corresponding lamp
            if error == 0
                app.UIAxes.Visible = 'on';
                app.CameraReadyLamp.Enable = 1;              
                frame = snapshot(app.cam);
                buffer = image(app.UIAxes, zeros(size(frame), 'uint8'));
                axis(app.UIAxes, 'image');
                
                preview(app.cam, buffer);
            end
        end

        % Button pushed function: EndVideoTransmissionButton
        function EndVideoTransmissionButtonPushed(app, event)
            % Disable video transmission by making the axes not visable
            app.UIAxes.Visible = 'off';
        end

        % Value changed function: VideoURLEditField
        function VideoURLEditFieldValueChanged(app, event)
            value = app.VideoURLEditField.Value;
            app.VideoURL = value;
        end

        % Button pushed function: GaugeTestButton
        function GaugeTestButtonPushed(app, event)
            % shows the effect of the gauges, for entertainment
            % purpose only
            for i = 1:100
                app.GaugeSteering.Value = app.GaugeSteering.Value + 1;
                app.Gauge.Value = app.Gauge.Value + 1;
                pause(0.005);
            end
            for i = 1:100
                app.GaugeSteering.Value = app.GaugeSteering.Value - 1;
                app.Gauge.Value = app.Gauge.Value - 1;
                pause(0.005);
            end
        end

        % Value changed function: ControllerSerialPortDropDown
        function ControllerSerialPortDropDownValueChanged(app, event)
            value = app.ControllerSerialPortDropDown.Value;
            app.XbeePort = value;
        end

        % Callback function
        function XbeeSerialPortDropDownValueChanged(app, event)
            value = app.XbeeSerialPortDropDown.Value;
            app.ControllerPort = value;
        end

        % Button pushed function: LAUNCHButton
        function LAUNCHButtonPushed(app, event)
           
           % display current state
           app.SystemlStopLamp.Enable = 0;
           
           % When the launch button is pushed, given the system is ready,
           % it calls on the main function
           if app.SystemReadyLamp.Enable == 1
                RobotRun(app);
           else
               % error handling
               f = msgbox('System Not Ready Yet', 'Error','error');
           end
        end

        % Button pushed function: ControllerConnectButton
        function ControllerConnectButtonPushed(app, event)
            error = 0;
            % Turn off connection warning
            warning('off', 'MATLAB:serial:fread:unsuccessfulRead'); % Supress fread timeout
            
            % Try connect to the serial port
            try
                app.joy = vrjoystick(1,'forcefeedback');
            catch ME
                error = 1;  % if connection failed, set error to 1  
                f = msgbox('Invalid Controller Port', 'Warning','Warn');
            end
            
            if error == 0   % if connection is successful
                % Turn on indication lamp, set connection flag
                app.ControllerConnectionReadyLamp.Enable = 1; 
                pause(0.001);   % Wait for the lamp to light up before inf while loop        
            end
        end

        % Button pushed function: XbeeConnectButton
        function XbeeConnectButtonPushed(app, event)
            error = 0;
            % Turn off connection warning
            warning('off', 'MATLAB:serial:fread:unsuccessfulRead'); % Supress fread timeout warning coz it's annoying
  
            % Try connect to the serial port
            try
                % Create a serial object in the private variable defined
                Comport = string(app.XbeeSerialPortTextArea.Value);
                app.SerialXbee = serialport(Comport,9600);
                
                % the termination is tuned to 0xFE to fit the data
                % strutcure being transmitted
                configureTerminator(app.SerialXbee,254);
            catch ME
                error = 1;  % if connection failed, set error to 1  
                f = msgbox('Invalid Xbee Port', 'Error','error');
            end
            
            if error == 0   % if connection is successful
                % Turn on indication lamp, set connection flag
                app.XbeeConnectionReadyLamp.Enable = 1; 
                pause(0.001);   % Wait 1 second for the lamp to pop up before inf while loop        
            end
        end

        % Button pushed function: SystemCheckButton
        function SystemCheckButtonPushed(app, event)
            % this function checks of the system xbee connection status
            % define message length
            length = uint8(0x03);
            if  app.XbeeConnectionReadyLamp.Enable == 1 && app.ControllerConnectionReadyLamp.Enable == 1
                % checking Xbee function
                transmit = compose_package([1,0],app.xbee_check,length,0);
                % transmit the predefined message
                transmit_message(app.SerialXbee,transmit);
                % verify PIC feedback
                receive_message(app.SerialXbee,app);
                % if PIC feedback indicates meesage recieved matches with
                % the predefined message
                if app.feedback == 0
                    app.XbeeModuleReadyLamp.Enable = 1;
                else
                    % else instruct users
                    f = msgbox('Xbee Module Check Failed', 'Warning','warn');
                end
                % if system check is succesful, system is ready
                if app.XbeeModuleReadyLamp.Enable == 1
                    app.SystemReadyLamp.Enable = 1;
                end
            else
                % else, instruct users
                f = msgbox('Xbee/Controller unconnected', 'Warning','warn');
            end
          
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartVideoTransmissionButton = uibutton(app.UIFigure, 'push');
            app.StartVideoTransmissionButton.ButtonPushedFcn = createCallbackFcn(app, @StartVideoTransmissionButtonPushed, true);
            app.StartVideoTransmissionButton.Text = 'Start Video Transmission';
            app.EndVideoTransmissionButton = uibutton(app.UIFigure, 'push');
            app.EndVideoTransmissionButton.ButtonPushedFcn = createCallbackFcn(app, @EndVideoTransmissionButtonPushed, true);
            app.EndVideoTransmissionButton.Text = 'End Video Transmission';
            app.VideoURLEditFieldLabel = uilabel(app.UIFigure);
            app.VideoURLEditFieldLabel.Text = 'Video URL';
            app.VideoURLEditField = uieditfield(app.UIFigure, 'text');
            app.VideoURLEditField.ValueChangedFcn = createCallbackFcn(app, @VideoURLEditFieldValueChanged, true);
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = '';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.GaugeTestButton = uibutton(app.UIFigure, 'push');
            app.GaugeTestButton.ButtonPushedFcn = createCallbackFcn(app, @GaugeTestButtonPushed, true);
            app.GaugeTestButton.Text = 'GaugeTest';
            app.ControllerConnectionReadyLampLabel = uilabel(app.UIFigure);
            app.ControllerConnectionReadyLampLabel.Text = 'Controller Connection Ready';
            app.ControllerConnectionReadyLamp = uilamp(app.UIFigure);
            app.ControllerConnectionReadyLamp.Enable = 'off';
            app.LAUNCHButton = uibutton(app.UIFigure, 'push');
            app.LAUNCHButton.ButtonPushedFcn = createCallbackFcn(app, @LAUNCHButtonPushed, true);
            app.LAUNCHButton.Text = 'LAUNCH';
            app.AutoControlLampLabel = uilabel(app.UIFigure);
            app.AutoControlLampLabel.Text = 'Auto Control';
            app.AutoControlLamp = uilamp(app.UIFigure);
            app.AutoControlLamp.Enable = 'off';
            app.SystemCheckButton = uibutton(app.UIFigure, 'push');
            app.SystemCheckButton.ButtonPushedFcn = createCallbackFcn(app, @SystemCheckButtonPushed, true);
            app.SystemCheckButton.Text = 'System Check';
            app.SystemReadyLampLabel = uilabel(app.UIFigure);
            app.SystemReadyLampLabel.Text = 'System Ready';
            app.SystemReadyLamp = uilamp(app.UIFigure);
            app.SystemReadyLamp.Enable = 'off';
            app.ManualControlLampLabel = uilabel(app.UIFigure);
            app.ManualControlLampLabel.Text = 'Manual Control';
            app.ManualControlLamp = uilamp(app.UIFigure);
            app.ManualControlLamp.Enable = 'off';
            app.ObstacleAvoidingLampLabel = uilabel(app.UIFigure);
            app.ObstacleAvoidingLampLabel.Text = 'Obstacle Avoiding';
            app.ObstacleAvoidingLamp = uilamp(app.UIFigure);
            app.ObstacleAvoidingLamp.Enable = 'off';
            app.SystemlStopLampLabel = uilabel(app.UIFigure);
            app.SystemlStopLampLabel.Text = 'Systeml Stop';
            app.SystemlStopLamp = uilamp(app.UIFigure);
            app.SystemlStopLamp.Enable = 'off';
            app.SteeringWheelReadyLampLabel_2 = uilabel(app.UIFigure);
            app.SteeringWheelReadyLampLabel_2.Text = 'System Status';
            app.SteeringWheelReadyLampLabel_3 = uilabel(app.UIFigure);
            app.SteeringWheelReadyLampLabel_3.Text = 'Operation Status';
            app.CameraReadyLampLabel = uilabel(app.UIFigure);
            app.CameraReadyLampLabel.Text = 'Camera Ready';
            app.CameraReadyLamp = uilamp(app.UIFigure);
            app.CameraReadyLamp.Enable = 'off';
            app.STOPButton = uibutton(app.UIFigure, 'state');
            app.STOPButton.Text = 'STOP';
            app.ControllerSerialPortDropDownLabel = uilabel(app.UIFigure);
            app.ControllerSerialPortDropDownLabel.Text = 'Controller Serial Port';
            app.ControllerSerialPortDropDown = uidropdown(app.UIFigure);
            app.ControllerSerialPortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5'};
            app.ControllerSerialPortDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllerSerialPortDropDownValueChanged, true);
            app.ControllerSerialPortDropDown.Value = 'COM1';
            app.ControllerConnectButton = uibutton(app.UIFigure, 'push');
            app.ControllerConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ControllerConnectButtonPushed, true);
            app.ControllerConnectButton.Text = 'Controller Connect';
            app.XbeeConnectButton = uibutton(app.UIFigure, 'push');
            app.XbeeConnectButton.ButtonPushedFcn = createCallbackFcn(app, @XbeeConnectButtonPushed, true);
            app.XbeeConnectButton.Text = 'Xbee Connect';
            app.ModeSwitchButton = uibutton(app.UIFigure, 'state');
            app.ModeSwitchButton.Text = 'Mode Switch';
            app.GaugeSteering = uigauge(app.UIFigure, 'linear');
            app.GaugeSteering.Limits = [-100 100];
            app.XbeeSerialPortTextAreaLabel = uilabel(app.UIFigure);
            app.XbeeSerialPortTextAreaLabel.Text = 'Xbee Serial Port';
            app.XbeeSerialPortTextArea = uitextarea(app.UIFigure);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.XbeeConnectionReadyLampLabel = uilabel(app.UIFigure);
            app.XbeeConnectionReadyLampLabel.Text = 'Xbee Connection Ready';
            app.XbeeConnectionReadyLamp = uilamp(app.UIFigure);
            app.XbeeModuleReadyLampLabel = uilabel(app.UIFigure);
            app.XbeeModuleReadyLampLabel.Text = 'Xbee Module Ready';
            app.XbeeModuleReadyLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end